classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        MainTab                 matlab.ui.container.Tab
        ChooseyourcandiesPanel  matlab.ui.container.Panel
        ClearButton             matlab.ui.control.Button
        OrderButton             matlab.ui.control.Button
        CandyBLabel             matlab.ui.control.Label
        CandyC                  matlab.ui.control.NumericEditField
        CandyB                  matlab.ui.control.NumericEditField
        CandyA                  matlab.ui.control.NumericEditField
        CandyCLabel             matlab.ui.control.Label
        CandyALabel             matlab.ui.control.Label
        MaintainenceTab         matlab.ui.container.Tab
        q6Spinner               matlab.ui.control.Spinner
        q6SpinnerLabel          matlab.ui.control.Label
        q5Spinner               matlab.ui.control.Spinner
        q5SpinnerLabel          matlab.ui.control.Label
        q4Spinner               matlab.ui.control.Spinner
        q4SpinnerLabel          matlab.ui.control.Label
        q3Spinner               matlab.ui.control.Spinner
        q3SpinnerLabel          matlab.ui.control.Label
        q2Spinner               matlab.ui.control.Spinner
        q2SpinnerLabel          matlab.ui.control.Label
        q1Spinner               matlab.ui.control.Spinner
        q1SpinnerLabel          matlab.ui.control.Label
        SweetSelectLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Program % The main program 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Program = MainProgram();
            qlims = app.Program.getQLims();


            app.q1Spinner.Limits = qlims(1, :);
            app.q2Spinner.Limits = qlims(2, :);
            app.q3Spinner.Limits = qlims(3, :);
            app.q4Spinner.Limits = qlims(4, :);
            app.q5Spinner.Limits = qlims(5, :);
            app.q6Spinner.Limits = qlims(6, :);


            app.q1Spinner.Step = abs(qlims(1, 1) - qlims(1, 2))/10;
            app.q2Spinner.Step = abs(qlims(2, 1) - qlims(2, 2))/10;
            app.q3Spinner.Step = abs(qlims(3, 1) - qlims(3, 2))/10;
            app.q4Spinner.Step = abs(qlims(4, 1) - qlims(4, 2))/10;
            app.q5Spinner.Step = abs(qlims(5, 1) - qlims(5, 2))/10;
            app.q6Spinner.Step = abs(qlims(6, 1) - qlims(6, 2))/10;
        end

        % Button pushed function: ClearButton
        function clearCandySelection(app, event)
            app.CandyA.Value = 0;
            app.CandyB.Value = 0;
            app.CandyC.Value = 0;
        end

        % Button pushed function: OrderButton
        function orderCandies(app, event)
            app.Program.putCandiesInBox(app.CandyA.Value, app.CandyB.Value, app.CandyC.Value);
        end

        % Value changed function: q1Spinner, q2Spinner, q3Spinner, 
        % ...and 3 other components
        function updateQValues(app, event)
            q = [app.q1Spinner.Value, app.q2Spinner.Value, app.q3Spinner.Value, app.q4Spinner.Value, app.q5Spinner.Value, app.q6Spinner.Value];
            app.Program.setQValues(q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SweetSelectLabel = uilabel(app.UIFigure);
            app.SweetSelectLabel.Text = 'Sweet Select';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChooseyourcandiesPanel = uipanel(app.MainTab);
            app.ChooseyourcandiesPanel.Title = 'Choose your candies';
            app.CandyALabel = uilabel(app.ChooseyourcandiesPanel);
            app.CandyALabel.Text = 'Candy A';
            app.CandyCLabel = uilabel(app.ChooseyourcandiesPanel);
            app.CandyCLabel.Text = 'Candy C';
            app.CandyA = uieditfield(app.ChooseyourcandiesPanel, 'numeric');
            app.CandyB = uieditfield(app.ChooseyourcandiesPanel, 'numeric');
            app.CandyC = uieditfield(app.ChooseyourcandiesPanel, 'numeric');
            app.CandyBLabel = uilabel(app.ChooseyourcandiesPanel);
            app.CandyBLabel.Text = 'Candy B';
            app.OrderButton = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.OrderButton.ButtonPushedFcn = createCallbackFcn(app, @orderCandies, true);
            app.OrderButton.Text = 'Order';
            app.ClearButton = uibutton(app.ChooseyourcandiesPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearCandySelection, true);
            app.ClearButton.Text = 'Clear';
            app.MaintainenceTab = uitab(app.TabGroup);
            app.MaintainenceTab.Title = 'Maintainence';
            app.q1SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.MaintainenceTab);
            app.q1Spinner.Step = 0.01;
            app.q1Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q2SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.MaintainenceTab);
            app.q2Spinner.Step = 0.01;
            app.q2Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q3SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.MaintainenceTab);
            app.q3Spinner.Step = 0.01;
            app.q3Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q4SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.MaintainenceTab);
            app.q4Spinner.Step = 0.01;
            app.q4Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q5SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.MaintainenceTab);
            app.q5Spinner.Step = 0.01;
            app.q5Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            app.q6SpinnerLabel = uilabel(app.MaintainenceTab);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.MaintainenceTab);
            app.q6Spinner.Step = 0.01;
            app.q6Spinner.ValueChangedFcn = createCallbackFcn(app, @updateQValues, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end